classdef derivative_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        df            matlab.ui.control.EditField
        x_0           matlab.ui.control.NumericEditField
        p             matlab.ui.control.NumericEditField
        Label         matlab.ui.control.Label
        fLabel        matlab.ui.control.Label
        empty_y       matlab.ui.control.Button
        rem_y         matlab.ui.control.Button
        add_y         matlab.ui.control.Button
        empty_x       matlab.ui.control.Button
        rem_x         matlab.ui.control.Button
        add_x         matlab.ui.control.Button
        x             matlab.ui.control.NumericEditField
        list_y        matlab.ui.control.ListBox
        y             matlab.ui.control.NumericEditField
        h             matlab.ui.control.NumericEditField
        hvalueLabel   matlab.ui.control.Label
        fvaluesLabel  matlab.ui.control.Label
        xvaluesLabel  matlab.ui.control.Label
        f             matlab.ui.control.EditField
        list_x        matlab.ui.control.ListBox
        radio         matlab.ui.container.ButtonGroup
        radio_p       matlab.ui.control.RadioButton
        radio_f       matlab.ui.control.RadioButton
        calc          matlab.ui.control.Button
        Panel         matlab.ui.container.Panel
        cons          matlab.ui.control.TextArea
        SolucionesComputacionalesaProblemasdeIngenieraPanel  matlab.ui.container.Panel
        DerivativeCalculatorusingTaylorSeriesLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [f_p, f_p_x, E] = dev_taylor(app, x_i, f_i, p, h_val, x_0)
            %x_i = [-0.1 0 0.2];
            %f_i = [4.157 4.02 4.441];
            
            %x_i = [1.1 1.19 1.199 1.2 1.201 1.21 1.3];
            %f_i = [0.4533 0.37166 0.3629 0.36236 0.36143 0.35302 0.2675];
        
            n = size(x_i);
            n = n(2);
            num_err = 3;
            max_df = n + num_err;
            syms df [1 max_df];
            syms h;
            syms a [1 n];
        
            h_i = 0:0:n;
            df_p = 0;
        
            for i = 1:n
                if x_i(i) ~= x_0
                    h_i(i) = (x_i(i) - x_0) / h_val;
                else
                    h_i(i) = 0;
                end
                if x_i(i) ~= x_0
                    df_p = df_p + a(i) * exp_taylor(app, df, h_i(i), h, max_df);
                else
                    df_p = df_p + a(i) * df(1);
                end
            end
            df_p = df_p / h ^ p;
            df_p = collect(df_p, df);
            df_p_terms = sym(children(df_p));
        
            eqs = sym(linspace(0, 0, n));
            for i = 1:n
                term = df_p_terms(i);
                if i - 1 == p
                    term = term == df(i);
                else
                    term = term == 0;
                end
                eqs(i) = term;
            end
            eqs = string(simplify(eqs));
        
            for i = 1:n
                temp = split(eqs(i), '|');
                index = 0;
                for j = 1:numel(temp)
                    if contains(temp(j), 'a')
                        index = j;
                        break;
                    end
                end
                eqs(i) = temp(index);
            end
        
            eqs = str2sym(eqs);
            [A, B] = equationsToMatrix(eqs);
            a_val = linsolve(A, B)';
        
            f_p = 0;
            syms f_ [1 n];
            for i = 1:n
                f_p = f_p + a_val(i) * f_(i);
            end
            f_p = f_p / h ^ p;
            f_p_x = double(subs(subs(f_p, f_, f_i), h, h_val));
            
            e = sym(linspace(0, 0, num_err));
            for i = 1:num_err
                e(i) = df_p_terms(n + i);
            end
            e = subs(e, a, a_val);
            E = 0;
            sw = 0;
            i = 0;
            while ~sw && i < num_err
                i = i + 1;
                if e(i) ~= 0
                    E = e(i);
                    sw = 1;
                end
            end
            if E ~= 0
                E = subs(E, df(n + i), df(n + i - 1));
            end
        end
        
        function t = exp_taylor(app, df, h_i, h, n)
            t = 0;
            for i = 1:n
                t = t + 1 / factorial(i - 1) * df(i) * (h_i * h)^(i - 1);
            end
        end
        
        function add_x_(app)
            x = app.x.Value;
            list = app.list_x;
            items = list.Items;
            if ~ismember(string(x), items)
                list.Items = [items string(x)];
                if numel(app.cons.Value)
                    app.cons.Value = '';
                end
            else
                app.cons.Value = 'This value does not exist anymore in the list.';
            end
        end
        
        function add_y_(app)
            y = app.y.Value;
            list = app.list_y;
            items = list.Items;
            list.Items = [items string(y)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: add_x
        function add_xButtonPushed(app, event)
            add_x_(app)
        end

        % Button pushed function: rem_x
        function rem_xButtonPushed(app, event)
            app.list_x.Items = setdiff(app.list_x.Items, app.list_x.Value);
            if numel(app.cons.Value)
                app.cons.Value = '';
            end
        end

        % Button pushed function: empty_x
        function empty_xButtonPushed(app, event)
            app.list_x.Items = {};
            if numel(app.cons.Value)
                app.cons.Value = '';
            end
        end

        % Value changed function: x
        function xValueChanged(app, event)
        
        end

        % Selection changed function: radio
        function radioSelectionChanged(app, event)
            sel = app.radio.SelectedObject.Text;
            f = strcmp(sel, 'Function')
            app.f.Enable = f;
            app.y.Enable = ~f;
            app.list_y.Enable = ~f;
            app.add_y.Enable = ~f;
            app.rem_y.Enable = ~f;
            app.empty_y.Enable = ~f;
            
        end

        % Button pushed function: add_y
        function add_yButtonPushed(app, event)
            add_y_(app);
        end

        % Button pushed function: rem_y
        function rem_yButtonPushed(app, event)
            app.list_y.Items = setdiff(app.list_y.Items, app.list_y.Value);
        end

        % Button pushed function: empty_y
        function empty_yButtonPushed(app, event)
            app.list_y.Items = {};
        end

        % Button pushed function: calc
        function calcButtonPushed(app, event)
            p = app.p.Value;
            x_0 = app.x_0.Value;
            h = app.h.Value;
            x_i = str2double(string(app.list_x.Items));
            if numel(x_i) < 2
                app.cons.Value = 'Please enter at least two points.';
                return;
            end
            if strcmp(app.radio.SelectedObject.Text, 'Function')
                try
                    f = str2func(['@(x)' app.f.Value]);
                    sym(f);
                catch
                    app.cons.Value = 'Function could not be recognized. Please try again (e.g. cos(x), e.^x+5).';
                    return;
                end
                f_i = f(x_i);
            else
                f_i = str2double(string(app.list_y.Items));
                if numel(f_i) ~= numel(x_i)
                    app.cons.Value = 'The number of x and f values must be the same.';
                    return;
                end
            end
%             x_i = [1.1 1.19 1.199 1.2 1.201 1.21 1.3];
%             h = 0.01;
%             p = 1;
%             x_0 = 1.1;
%             %f_i = [0.4533 0.37166 0.3629 0.36236 0.36143 0.35302 0.2675];
%             f = str2func(['@(x)' 'cos(x)']);
%             f_i = f(x_i);
            
            [f_p_, f_p_x, E_] = dev_taylor(app, x_i, f_i, p, h, x_0);
            app.df.Value = string(f_p_x);
            
            syms E f_p;
            f_p_ = strrep(string(f_p == f_p_), '==', '=');
            msg1 = strcat(['Formula for the derivative: ', {' '}, f_p_]);
            if E_ ~= 0
                E_ = strrep(string(E == E_), '==', '=');
                msg2 = strcat(['Error term: ', {' '}, E_]);
            else
                msg2 = 'Error could not be calculated.';
            end
            app.cons.Value = [msg1 '' msg2];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SolucionesComputacionalesaProblemasdeIngenieraPanel = uipanel(app.UIFigure);
            app.SolucionesComputacionalesaProblemasdeIngenieraPanel.Title = 'Soluciones Computacionales a Problemas de Ingeniería';
            app.DerivativeCalculatorusingTaylorSeriesLabel = uilabel(app.SolucionesComputacionalesaProblemasdeIngenieraPanel);
            app.DerivativeCalculatorusingTaylorSeriesLabel.Text = 'Derivative Calculator using Taylor Series';
            app.Panel = uipanel(app.UIFigure);
            app.cons = uitextarea(app.Panel);
            app.cons.Tag = 'cons';
            app.cons.Editable = 'off';
            app.calc = uibutton(app.UIFigure, 'push');
            app.calc.ButtonPushedFcn = createCallbackFcn(app, @calcButtonPushed, true);
            app.calc.Tag = 'calc';
            app.calc.Text = 'Go';
            app.radio = uibuttongroup(app.UIFigure);
            app.radio.SelectionChangedFcn = createCallbackFcn(app, @radioSelectionChanged, true);
            app.radio.Tag = 'radio';
            app.radio_f = uiradiobutton(app.radio);
            app.radio_f.Tag = 'radio_f';
            app.radio_f.Text = 'Function';
            app.radio_f.Value = true;
            app.radio_p = uiradiobutton(app.radio);
            app.radio_p.Tag = 'radio_p';
            app.radio_p.Text = 'Points';
            app.list_x = uilistbox(app.UIFigure);
            app.list_x.Items = {};
            app.list_x.Multiselect = 'on';
            app.list_x.Tag = 'list_x';
            app.list_x.Value = {};
            app.f = uieditfield(app.UIFigure, 'text');
            app.f.Tag = 'f';
            app.xvaluesLabel = uilabel(app.UIFigure);
            app.xvaluesLabel.Text = 'x values';
            app.fvaluesLabel = uilabel(app.UIFigure);
            app.fvaluesLabel.Text = 'f values';
            app.hvalueLabel = uilabel(app.UIFigure);
            app.hvalueLabel.Text = 'h value';
            app.h = uieditfield(app.UIFigure, 'numeric');
            app.h.LowerLimitInclusive = 'off';
            app.h.UpperLimitInclusive = 'off';
            app.h.Limits = [0 1];
            app.h.Tag = 'h';
            app.h.Value = 0.1;
            app.y = uieditfield(app.UIFigure, 'numeric');
            app.y.Tag = 'y';
            app.y.Enable = 'off';
            app.list_y = uilistbox(app.UIFigure);
            app.list_y.Items = {};
            app.list_y.Multiselect = 'on';
            app.list_y.Tag = 'list_y';
            app.list_y.Enable = 'off';
            app.list_y.Value = {};
            app.x = uieditfield(app.UIFigure, 'numeric');
            app.x.ValueChangedFcn = createCallbackFcn(app, @xValueChanged, true);
            app.x.Tag = 'x';
            app.add_x = uibutton(app.UIFigure, 'push');
            app.add_x.ButtonPushedFcn = createCallbackFcn(app, @add_xButtonPushed, true);
            app.add_x.Tag = 'add_x';
            app.add_x.Text = 'Add';
            app.rem_x = uibutton(app.UIFigure, 'push');
            app.rem_x.ButtonPushedFcn = createCallbackFcn(app, @rem_xButtonPushed, true);
            app.rem_x.Tag = 'rem_x';
            app.rem_x.Text = 'Remove';
            app.empty_x = uibutton(app.UIFigure, 'push');
            app.empty_x.ButtonPushedFcn = createCallbackFcn(app, @empty_xButtonPushed, true);
            app.empty_x.Tag = 'empty_x';
            app.empty_x.Text = 'Empty';
            app.add_y = uibutton(app.UIFigure, 'push');
            app.add_y.ButtonPushedFcn = createCallbackFcn(app, @add_yButtonPushed, true);
            app.add_y.Tag = 'add_y';
            app.add_y.Enable = 'off';
            app.add_y.Text = 'Add';
            app.rem_y = uibutton(app.UIFigure, 'push');
            app.rem_y.ButtonPushedFcn = createCallbackFcn(app, @rem_yButtonPushed, true);
            app.rem_y.Tag = 'rem_y';
            app.rem_y.Enable = 'off';
            app.rem_y.Text = 'Remove';
            app.empty_y = uibutton(app.UIFigure, 'push');
            app.empty_y.ButtonPushedFcn = createCallbackFcn(app, @empty_yButtonPushed, true);
            app.empty_y.Tag = 'empty_y';
            app.empty_y.Enable = 'off';
            app.empty_y.Text = 'Empty';
            app.fLabel = uilabel(app.UIFigure);
            app.fLabel.Text = 'f';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.p = uieditfield(app.UIFigure, 'numeric');
            app.p.Limits = [0 9];
            app.p.Tag = 'p';
            app.x_0 = uieditfield(app.UIFigure, 'numeric');
            app.x_0.Tag = 'x0';
            app.df = uieditfield(app.UIFigure, 'text');
            app.df.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = derivative_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end